classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label             matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        Label_4           matlab.ui.control.Label
        EditField         matlab.ui.control.EditField
        EditField_2Label  matlab.ui.control.Label
        EditField_2       matlab.ui.control.EditField
        EditField_3Label  matlab.ui.control.Label
        EditField_3       matlab.ui.control.EditField
        EditField_4       matlab.ui.control.EditField
        EditField_5       matlab.ui.control.EditField
        EditField_6Label  matlab.ui.control.Label
        EditField_6       matlab.ui.control.EditField
        Label_5           matlab.ui.control.Label
        EditField_7       matlab.ui.control.NumericEditField
        EditField_8Label  matlab.ui.control.Label
        EditField_8       matlab.ui.control.NumericEditField
        EditField_9Label  matlab.ui.control.Label
        EditField_9       matlab.ui.control.NumericEditField
        Button            matlab.ui.control.StateButton
        Label_6           matlab.ui.control.Label
        Label_7           matlab.ui.control.Label
        EditField_10      matlab.ui.control.NumericEditField
        Label_8           matlab.ui.control.Label
        Label_9           matlab.ui.control.Label
    end

    
    properties (Access = private)
        subj1_score;
        subj2_score;
        subj3_score;
        subsum;
        sumlim;
       %% text;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function click_button(app, event)

            app.subsum = app.subj1_score+app.subj3_score+app.subj2_score;
            app.Label_8.Text = sprintf('%d',app.subsum);
            if (app.subsum>=app.sumlim)
                app.Label_9.Text = 'Поздравляем с зачислением!';
            else
                app.Label_9.Text = 'Попробуйте в следующем году :(';
            end
            
        end

        % Value changed function: EditField_7
        function subj1_score_entered(app, event)
            app.subj1_score = app.EditField_7.Value;
            
        end

        % Value changed function: EditField_8
        function subj2_score_entered(app, event)
            app.subj2_score = app.EditField_8.Value;
            
        end

        % Value changed function: EditField_9
        function subj3_score_entered(app, event)
            app.subj3_score = app.EditField_9.Value;
            
        end

        % Value changed function: EditField_10
        function subj_limit_entered(app, event)
            app.sumlim = app.EditField_10.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ФИО';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Предмет';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Баллы ЕГЭ';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {''; ''};
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = {''; ''};
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = {''; ''};
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Text = {''; ''};
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.Limits = [0 100];
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @subj1_score_entered, true);
            app.EditField_8Label = uilabel(app.UIFigure);
            app.EditField_8Label.Text = '';
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.Limits = [0 100];
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @subj2_score_entered, true);
            app.EditField_9Label = uilabel(app.UIFigure);
            app.EditField_9Label.Text = '';
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9.Limits = [0 100];
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @subj3_score_entered, true);
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @click_button, true);
            app.Button.Text = 'Вычислить';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Сумма баллов:';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'Проходной балл: ';
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @subj_limit_entered, true);
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end