classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        LeftPanel    matlab.ui.container.Panel
        UIAxes       matlab.ui.control.UIAxes
        RightPanel   matlab.ui.container.Panel
        Button       matlab.ui.control.Button
        ButtonGroup  matlab.ui.container.ButtonGroup
        CheckBox     matlab.ui.control.CheckBox
        CheckBox_2   matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        val_check_sin = 0;
        val_check_cos = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CheckBox
        function sin_check_on(app, event)
            app.val_check_sin = app.CheckBox.Value;
        end

        % Value changed function: CheckBox_2
        function cos_check_on(app, event)
            app.val_check_cos = app.CheckBox_2.Value;
        end

        % Button pushed function: Button
        function button_graph(app, event)

            if ((app.val_check_sin == 1) && (app.val_check_cos == 0))
                x = 0:0.01:4*pi;
                y_sin = sin(x);
                plot(app.UIAxes,x,y_sin,'g');
            end
            if ((app.val_check_cos==1) && (app.val_check_sin==0))
                x = 0:0.01:4*pi;
                y_cos = cos(x);
                plot(app.UIAxes,x,y_cos,'b');
            end 
            if ((app.val_check_cos==1) && (app.val_check_sin==1))
                x = 0:0.01:4*pi;
                y_sin = sin(x); 
                y_cos = cos(x);
                plot(app.UIAxes,x,y_sin,'g',x,y_cos,'b');
            end
            if ((app.val_check_cos==0) && (app.val_check_sin==0))
                plot(app.UIAxes,0,0);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {458, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'График')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 12.5663706143592];
            app.RightPanel = uipanel(app.GridLayout);
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @button_graph, true);
            app.Button.Text = 'Построить';
            app.ButtonGroup = uibuttongroup(app.RightPanel);
            app.ButtonGroup.Title = 'Группа преключателей';
            app.CheckBox = uicheckbox(app.ButtonGroup);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @sin_check_on, true);
            app.CheckBox.Text = 'Синус';
            app.CheckBox_2 = uicheckbox(app.ButtonGroup);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @cos_check_on, true);
            app.CheckBox_2.Text = 'Косинус';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end