classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Label               matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        Label_3             matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
        Label_5             matlab.ui.control.Label
        x2EditFieldLabel    matlab.ui.control.Label
        x2EditField         matlab.ui.control.NumericEditField
        x3EditFieldLabel    matlab.ui.control.Label
        x3EditField         matlab.ui.control.NumericEditField
        x4EditFieldLabel    matlab.ui.control.Label
        x4EditField         matlab.ui.control.NumericEditField
        x5EditFieldLabel    matlab.ui.control.Label
        x5EditField         matlab.ui.control.NumericEditField
        x1EditField_2Label  matlab.ui.control.Label
        x1EditField_2       matlab.ui.control.NumericEditField
        x2EditField_2Label  matlab.ui.control.Label
        x2EditField_2       matlab.ui.control.NumericEditField
        x3EditField_2Label  matlab.ui.control.Label
        x3EditField_2       matlab.ui.control.NumericEditField
        x4EditField_2Label  matlab.ui.control.Label
        x4EditField_2       matlab.ui.control.NumericEditField
        x5EditField_2Label  matlab.ui.control.Label
        x5EditField_2       matlab.ui.control.NumericEditField
        x1EditField_3Label  matlab.ui.control.Label
        x1EditField_3       matlab.ui.control.NumericEditField
        x2EditField_3Label  matlab.ui.control.Label
        x2EditField_3       matlab.ui.control.NumericEditField
        x3EditField_3Label  matlab.ui.control.Label
        x3EditField_3       matlab.ui.control.NumericEditField
        x4EditField_3Label  matlab.ui.control.Label
        x4EditField_3       matlab.ui.control.NumericEditField
        x5EditField_3Label  matlab.ui.control.Label
        x5EditField_3       matlab.ui.control.NumericEditField
        x1EditField_4Label  matlab.ui.control.Label
        x1EditField_4       matlab.ui.control.NumericEditField
        x2EditField_4Label  matlab.ui.control.Label
        x2EditField_4       matlab.ui.control.NumericEditField
        x3EditField_5Label  matlab.ui.control.Label
        x3EditField_5       matlab.ui.control.NumericEditField
        x4EditField_5Label  matlab.ui.control.Label
        x4EditField_5       matlab.ui.control.NumericEditField
        x5EditField_4Label  matlab.ui.control.Label
        x5EditField_4       matlab.ui.control.NumericEditField
        x1EditField_5Label  matlab.ui.control.Label
        x1EditField_5       matlab.ui.control.NumericEditField
        x2EditField_5Label  matlab.ui.control.Label
        x2EditField_5       matlab.ui.control.NumericEditField
        x3EditField_4Label  matlab.ui.control.Label
        x3EditField_4       matlab.ui.control.NumericEditField
        x4EditField_6Label  matlab.ui.control.Label
        x4EditField_6       matlab.ui.control.NumericEditField
        x5EditField_5Label  matlab.ui.control.Label
        x5EditField_5       matlab.ui.control.NumericEditField
        EditField_2         matlab.ui.control.NumericEditField
        EditField_3         matlab.ui.control.NumericEditField
        EditField_4         matlab.ui.control.NumericEditField
        EditField_5         matlab.ui.control.NumericEditField
        Label_6             matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        Label_7             matlab.ui.control.Label
        DropDown_2          matlab.ui.control.DropDown
        Button              matlab.ui.control.Button
        Label_8             matlab.ui.control.Label
        x1EditFieldLabel    matlab.ui.control.Label
        x1EditField         matlab.ui.control.NumericEditField
        Button_2            matlab.ui.control.Button
        Label_9             matlab.ui.control.Label
        Label_10            matlab.ui.control.Label
        EditField           matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        n = 5;
        m = 5;
        a = zeros(5,5);
        b = zeros(5,1);
    end
    
    methods (Access = private)
        
        function [] = SolveLinearEquations(app,a,b,n,m)
                     syms x1 x2 x3 x4 x5;
                     mass = [x1;x2;x3;x4;x5];      
 
                     
                     if (all(all(a==0)) && all(b==0))
                         app.Label_8.Text = sprintf("Система имеет бесконечно много решений");
                         return;
                     end
                     
                     if (all(b==0))
                         x = rref(a);
                     else
                         x = rref([a b]);
                     end
                     LNZ = n-rank(a);
                     
                     x(all(x==0,2),:) = [];
                     
                     if (~all(b==0))
                        x_free_no_zeros = x(:,n+1);
                        x(:,n+1) = [];
                     else 
                         x_free_no_zeros = zeros(size(x,1),1);
                         x(all(x==0,2),:) = []; 
                     end
                     r = rank(a);
                                          
                     if (rank(a)~=rank([a b]))
                         app.Label_8.Text = sprintf("Система несовместна");
                         return;
                     elseif (n==r) 
                         FSS = linsolve(a,b);
                         for i=1:n
                                app.Label_8.Text = app.Label_8.Text+sprintf("%s = %.3f\n",mass(i),FSS(i,1)); 
                         end
                         return;
                     end
                     
                     x_z = x;
                     if (LNZ==n-1)
                        switch n
                            case 2
                                eq =x(1,1)*mass(1)+x(1,2)*mass(2)-x_free_no_zeros;
                                app.Label_8.Text =app.Label_8.Text + sprintf("%s = %s\n", mass(1),solve(eq));
                            case 3
                                eq = x(1,1)*mass(1)+x(1,2)*mass(2)+x(1,3)*mass(3)-x_free_no_zeros;
                                app.Label_8.Text =app.Label_8.Text + sprintf("%s = %s\n", mass(1),solve(eq));
                            case 4
                                eq = x(1,1)*mass(1)+x(1,2)*mass(2)+x(1,3)*mass(3)+x(1,4)*mass(4)-x_free_no_zeros;
                                app.Label_8.Text =app.Label_8.Text + sprintf("%s = %s\n", mass(1),solve(eq));
                            case 5
                                eq = x(1,1)*mass(1)+x(1,2)*mass(2)+x(1,3)*mass(3)+x(1,4)*mass(4)+x(1,5)*mass(5)-x_free_no_zeros;
                                app.Label_8.Text =app.Label_8.Text + sprintf("%s = %s\n", mass(1),solve(eq));    
                        end
                         return;
                     end
                     
                     flag_IsLNZ = 0;
            switch LNZ
                        case 1
                          for i=n:-1:1
                              x_z = x;
                              x_z(:,i) = [];
                              x_zz = x_z;
                              if (det(x_zz)~=0)
                                  num_LNZ = i;
                                  break;
                              else 
                                  continue;
                              end
                          end
%                             num_LZ = 1:n;
%                             num_LZ = num_LZ(num_LZ~=num_LNZ);
%                             a_FSS = x_free_no_zeros;
%                             x_z = x;
%                             zam = x(:,num_LNZ);
%                             a_FSS = [a_FSS -zam];
%                             x_z(:,num_LNZ) = [];
%                             res = rref([x_z a_FSS]);
%                             for i=1:size(res,1)
%                                 eq = res(i,i)*mass(num_LZ(1))-res(i,size(res,1)+1)-res(i,size(res,1)+2)*mass(num_LNZ) ==0;
%                                 app.Label_8.Text =app.Label_8.Text + sprintf("%s = %s\n", mass(num_LZ(i)),solve(eq));
%                             end
                               res = null(a,'r');
                              for i=1:m
                                  if (i~=num_LNZ)
                                  app.Label_8.Text =app.Label_8.Text + sprintf("%s = %.3f+%.3f*%s\n",mass(i),b(i)/a(i,i),res(i),mass(num_LNZ));
                                  end
                              end

                        case 2
                            for i=n:-1:1
                                for j=n-1:-1:1
                                    x_z = x;
                                    x_z(:,i) = [];
                                    x_z(:,j) = [];
                                    x_zz = x_z;
                                    if (det(x_zz)~=0)
                                        num_LNZ = [j;i];
                                        flag_IsLNZ = 1;
                                        break;
                                    else
                                        continue;
                                    end
                                end
                                if (flag_IsLNZ==1) 
                                    break;
                                end
                            end
%                             num_LZ = 1:n;
%                             num_LZ = num_LZ(all(num_LZ~=num_LNZ));
                            res = null(a,'r');
                              for i=1:m
                                  if (i~=num_LNZ(1) && i~=num_LNZ(2))
                                  app.Label_8.Text =app.Label_8.Text + sprintf("%s = %.3f+%.3f*%s+%.3f*%s\n",mass(i),x_free_no_zeros(i),res(i,1),mass(num_LNZ(1)),res(i,2),mass(num_LNZ(2)));
                                  end
                              end
%                             a_FSS = x_free_no_zeros;
%                             x_z = x;
%                             zam = x(:,num_LNZ(1));
%                             x_z(:,num_LNZ(1)) = [];
%                             a_FSS = [a_FSS -zam];
%                             zam = x_z(:,num_LNZ(2)-1);
%                             a_FSS = [a_FSS -zam];
%                             x_z(:,num_LNZ(2)-1) = [];
%                             res = rref([x_z a_FSS]);
%                             for i=1:size(res,1)
%                                 eq = res(i,i)*mass(num_LZ(1))-res(i,size(res,1)+1)-res(i,size(res,1)+2)*mass(num_LNZ(1))-res(i,size(res,1)+3)*mass(num_LNZ(2)) ==0;
%                                 app.Label_8.Text =app.Label_8.Text + sprintf("%s = %s\n", mass(num_LZ(i)),solve(eq));
%                             end
                            
                        case 3
                            for i=n:-1:1
                                for j=n-1:-1:1
                                    for k=n-2:-1:1
                                        x_z = x;
                                        x_z(:,i) = [];
                                        x_z(:,j) = [];
                                        x_z(:,k) = [];
                                        x_zz = x_z;
                                        if (det(x_zz)~=0)
                                            num_LNZ = [k;j;i];
                                            flag_IsLNZ = 1;
                                            break;
                                        else
                                            continue;
                                        end
                                    end
                                    if (flag_IsLNZ==1) 
                                        break;
                                    end
                                end
                                if (flag_IsLNZ==1) 
                                    break;
                                end
                            end
%                             num_LZ = 1:n;
%                             num_LZ = num_LZ(all(num_LZ~=num_LNZ));
%                             a_FSS = x_free_no_zeros;
%                             x_z = x;
%                             zam = x(:,num_LNZ(1));
%                             x_z(:,num_LNZ(1)) = [];
%                             a_FSS = [a_FSS -zam];
%                             zam = x_z(:,num_LNZ(2)-1);
%                             a_FSS = [a_FSS -zam];
%                             x_z(:,num_LNZ(2)-1) = [];
%                             zam = x_z(:,num_LNZ(3)-2);
%                             a_FSS = [a_FSS -zam];
%                             x_z(:,num_LNZ(3)-2) = [];
%                             res = rref([x_z a_FSS]);
%                             for i=1:size(res,1)
%                                 eq = res(i,i)*mass(num_LZ(1))-res(i,size(res,1)+1)-res(i,size(res,1)+2)*mass(num_LNZ(1))-res(i,size(res,1)+3)*mass(num_LNZ(2)) - res(i,size(res,1)+4)*mass(num_LNZ(3)) ==0;
%                                 app.Label_8.Text =app.Label_8.Text + sprintf("%s = %s\n", mass(num_LZ(i)),solve(eq));
%                             end
%                      end
                              res = null(a,'r');
                              for i=1:m
                                  if (i~=num_LNZ(1) && i~=num_LNZ(2) && i~=numLNZ(3))
                                  app.Label_8.Text =app.Label_8.Text + sprintf("%s = %.3f+%.3f*%s+%.3f*%s+%.3f*%s\n",mass(i),x_free_no_zeros(i),res(i,1),mass(num_LNZ(1)),res(i,2),mass(num_LNZ(2)),res(i,3),mass(num_LNZ(3)));
                                  end
                              end
                    
                                
             end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            z = app.n;
            app.n = str2num(app.DropDown.Value);
            app.Label_8.Text = sprintf("");
            if (app.n<z)
                app.a = [app.a zeros(app.m,z-app.n)];
            elseif(app.n>z)
                app.a = app.a(:,1:(z-app.n));
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            app.m = str2num(app.DropDown_2.Value);
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x1EditField
        function x1EditFieldValueChanged(app, event)
            app.a(1,1) = app.x1EditField.Value;
            app.Label_8.Text = sprintf("");
            
        end

        % Value changed function: x2EditField
        function x2EditFieldValueChanged(app, event)
            app.a(1,2) = app.x2EditField.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x3EditField
        function x3EditFieldValueChanged(app, event)
            app.a(1,3) = app.x3EditField.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x4EditField
        function x4EditFieldValueChanged(app, event)
            app.a(1,4) = app.x4EditField.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x5EditField
        function x5EditFieldValueChanged(app, event)
            app.a(1,5) = app.x5EditField.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x1EditField_2
        function x1EditField_2ValueChanged(app, event)
            app.a(2,1) = app.x1EditField_2.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x2EditField_2
        function x2EditField_2ValueChanged(app, event)
            app.a(2,2) = app.x2EditField_2.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x3EditField_2
        function x3EditField_2ValueChanged(app, event)
            app.a(2,3) = app.x3EditField_2.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x4EditField_2
        function x4EditField_2ValueChanged(app, event)
            app.a(2,4) = app.x4EditField_2.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x5EditField_2
        function x5EditField_2ValueChanged(app, event)
            app.a(2,5) = app.x5EditField_2.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x1EditField_3
        function x1EditField_3ValueChanged(app, event)
            app.a(3,1) = app.x1EditField_3.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x2EditField_3
        function x2EditField_3ValueChanged(app, event)
            app.a(3,2) = app.x2EditField_3.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x3EditField_3
        function x3EditField_3ValueChanged(app, event)
            app.a(3,3) = app.x3EditField_3.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x4EditField_3
        function x4EditField_3ValueChanged(app, event)
            app.a(3,4) = app.x4EditField_3.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x5EditField_3
        function x5EditField_3ValueChanged(app, event)
            app.a(3,5) = app.x5EditField_3.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x1EditField_4
        function x1EditField_4ValueChanged(app, event)
            app.a(4,1) = app.x1EditField_4.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x2EditField_4
        function x2EditField_4ValueChanged(app, event)
            app.a(4,2) = app.x2EditField_4.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x3EditField_5
        function x3EditField_5ValueChanged(app, event)
            app.a(4,3) = app.x3EditField_5.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x4EditField_5
        function x4EditField_5ValueChanged(app, event)
            app.a(4,4) = app.x4EditField_5.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x5EditField_4
        function x5EditField_4ValueChanged(app, event)
            app.a(4,5) = app.x5EditField_4.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x1EditField_5
        function x1EditField_5ValueChanged(app, event)
            app.a(5,1) = app.x1EditField_5.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x2EditField_5
        function x2EditField_5ValueChanged(app, event)
            app.a(5,2) = app.x2EditField_5.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x3EditField_4
        function x3EditField_4ValueChanged(app, event)
            app.a(5,3) = app.x3EditField_4.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x4EditField_6
        function x4EditField_6ValueChanged(app, event)
            app.a(5,4) = app.x4EditField_6.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: x5EditField_5
        function x5EditField_5ValueChanged(app, event)
            app.a(5,5) = app.x5EditField_5.Value;
            app.Label_8.Text = sprintf("");
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            app.b(1,1) = app.EditField.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.b(2,1) = app.EditField_2.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.b(3,1) = app.EditField_3.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            app.b(4,1) = app.EditField_4.Value;
            app.Label_8.Text = sprintf("");
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            app.b(5,1) = app.EditField_5.Value;
            app.Label_8.Text = sprintf("");
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %msgbox(sprintf("%d",app.a));
            if (app.DropDown.Value ~="5" || app.DropDown_2.Value ~="5")
                app.a = app.a(1:app.m,1:app.n);
                app.b = app.b(1:app.m,1);
            else
            syms x1 x2 x3 x4 x5;
            mass = [x1;x2;x3;x4;x5];
            app.a = app.a(1:app.m,1:app.n);
            app.b = app.b(1:app.m,1);
            %msgbox(sprintf("%d %d",app.a,app.b));
%             if (all(all(app.a))==0 && sum(any(app.b))~=0)
%                 app.Label_8.Text = sprintf("Система несовместна");
%                 return;
%             end
            app.m = app.m-sum(~any(app.a,2));
            app.a(all(app.a==0,2),:) = [];
            app.b = app.b(1:app.m,1);
            %msgbox(sprintf("%d",app.a));
            if ((app.m==1) && (sum(any(app.a,1))==1))
                app.Label_8.Text = sprintf("%s = %.3f\n", mass(app.a~=0),app.b(1)/app.a(app.a~=0));
                %fprintf("%s = %.3f\n", mass(a~=0),b(1)/a(a~=0))
                return;
            end  
            app.a = app.a(1:app.m,1:app.n);
            app.b = app.b(1:app.m,1);

            app.n = app.n-sum(~any(app.a,1));
            app.a(:,all(app.a==0,1)) = [];
        end
 
            %msgbox(sprintf("%d %d",app.a,app.b));
            %msgbox(sprintf("%d",app.a));
            %%app.Label_8.Text = sprintf("%d", app.a);
            SolveLinearEquations(app,app.a,app.b,app.n,app.m);
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.a = zeros(5,5);
            app.b = zeros(5,1);
            app.n = 5;
            app.m = 5;
            app.x1EditField.Value = 0; app.x1EditField_2.Value = 0; app.x1EditField_3.Value = 0; app.x1EditField_4.Value = 0; app.x1EditField_5.Value = 0;
            app.x2EditField.Value = 0; app.x2EditField_2.Value = 0; app.x2EditField_3.Value = 0; app.x2EditField_4.Value = 0; app.x2EditField_5.Value = 0;
            app.x3EditField.Value = 0; app.x3EditField_2.Value = 0; app.x3EditField_3.Value = 0; app.x3EditField_4.Value = 0; app.x4EditField_6.Value = 0;
            app.x4EditField.Value = 0; app.x4EditField_2.Value = 0; app.x4EditField_3.Value = 0; app.x3EditField_5.Value = 0; app.x4EditField_5.Value = 0;
            app.x5EditField.Value = 0; app.x5EditField_2.Value = 0; app.x5EditField_3.Value = 0; app.x5EditField_4.Value = 0; app.x5EditField_5.Value = 0;
            
            app.EditField.Value = 0; app.EditField_2.Value = 0; app.EditField_3.Value = 0; app.EditField_4.Value = 0; app.EditField_5.Value = 0;
            
            app.DropDown.Value = "5"; app.DropDown_2.Value = "5";
            app.Label_8.Text = sprintf("");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '=';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '=';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '=';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '=';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '=';
            app.x2EditFieldLabel = uilabel(app.UIFigure);
            app.x2EditFieldLabel.Text = 'x2+';
            app.x2EditField = uieditfield(app.UIFigure, 'numeric');
            app.x2EditField.ValueChangedFcn = createCallbackFcn(app, @x2EditFieldValueChanged, true);
            app.x3EditFieldLabel = uilabel(app.UIFigure);
            app.x3EditFieldLabel.Text = 'x3+';
            app.x3EditField = uieditfield(app.UIFigure, 'numeric');
            app.x3EditField.ValueChangedFcn = createCallbackFcn(app, @x3EditFieldValueChanged, true);
            app.x4EditFieldLabel = uilabel(app.UIFigure);
            app.x4EditFieldLabel.Text = 'x4+';
            app.x4EditField = uieditfield(app.UIFigure, 'numeric');
            app.x4EditField.ValueChangedFcn = createCallbackFcn(app, @x4EditFieldValueChanged, true);
            app.x5EditFieldLabel = uilabel(app.UIFigure);
            app.x5EditFieldLabel.Text = 'x5';
            app.x5EditField = uieditfield(app.UIFigure, 'numeric');
            app.x5EditField.ValueChangedFcn = createCallbackFcn(app, @x5EditFieldValueChanged, true);
            app.x1EditField_2Label = uilabel(app.UIFigure);
            app.x1EditField_2Label.Text = 'x1+';
            app.x1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.x1EditField_2.ValueChangedFcn = createCallbackFcn(app, @x1EditField_2ValueChanged, true);
            app.x2EditField_2Label = uilabel(app.UIFigure);
            app.x2EditField_2Label.Text = 'x2+';
            app.x2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.x2EditField_2.ValueChangedFcn = createCallbackFcn(app, @x2EditField_2ValueChanged, true);
            app.x3EditField_2Label = uilabel(app.UIFigure);
            app.x3EditField_2Label.Text = 'x3+';
            app.x3EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.x3EditField_2.ValueChangedFcn = createCallbackFcn(app, @x3EditField_2ValueChanged, true);
            app.x4EditField_2Label = uilabel(app.UIFigure);
            app.x4EditField_2Label.Text = 'x4+';
            app.x4EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.x4EditField_2.ValueChangedFcn = createCallbackFcn(app, @x4EditField_2ValueChanged, true);
            app.x5EditField_2Label = uilabel(app.UIFigure);
            app.x5EditField_2Label.Text = 'x5';
            app.x5EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.x5EditField_2.ValueChangedFcn = createCallbackFcn(app, @x5EditField_2ValueChanged, true);
            app.x1EditField_3Label = uilabel(app.UIFigure);
            app.x1EditField_3Label.Text = 'x1+';
            app.x1EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.x1EditField_3.ValueChangedFcn = createCallbackFcn(app, @x1EditField_3ValueChanged, true);
            app.x2EditField_3Label = uilabel(app.UIFigure);
            app.x2EditField_3Label.Text = 'x2+';
            app.x2EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.x2EditField_3.ValueChangedFcn = createCallbackFcn(app, @x2EditField_3ValueChanged, true);
            app.x3EditField_3Label = uilabel(app.UIFigure);
            app.x3EditField_3Label.Text = 'x3+';
            app.x3EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.x3EditField_3.ValueChangedFcn = createCallbackFcn(app, @x3EditField_3ValueChanged, true);
            app.x4EditField_3Label = uilabel(app.UIFigure);
            app.x4EditField_3Label.Text = 'x4+';
            app.x4EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.x4EditField_3.ValueChangedFcn = createCallbackFcn(app, @x4EditField_3ValueChanged, true);
            app.x5EditField_3Label = uilabel(app.UIFigure);
            app.x5EditField_3Label.Text = 'x5';
            app.x5EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.x5EditField_3.ValueChangedFcn = createCallbackFcn(app, @x5EditField_3ValueChanged, true);
            app.x1EditField_4Label = uilabel(app.UIFigure);
            app.x1EditField_4Label.Text = 'x1+';
            app.x1EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.x1EditField_4.ValueChangedFcn = createCallbackFcn(app, @x1EditField_4ValueChanged, true);
            app.x2EditField_4Label = uilabel(app.UIFigure);
            app.x2EditField_4Label.Text = 'x2+';
            app.x2EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.x2EditField_4.ValueChangedFcn = createCallbackFcn(app, @x2EditField_4ValueChanged, true);
            app.x3EditField_5Label = uilabel(app.UIFigure);
            app.x3EditField_5Label.Text = 'x3+';
            app.x3EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.x3EditField_5.ValueChangedFcn = createCallbackFcn(app, @x3EditField_5ValueChanged, true);
            app.x4EditField_5Label = uilabel(app.UIFigure);
            app.x4EditField_5Label.Text = 'x4+';
            app.x4EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.x4EditField_5.ValueChangedFcn = createCallbackFcn(app, @x4EditField_5ValueChanged, true);
            app.x5EditField_4Label = uilabel(app.UIFigure);
            app.x5EditField_4Label.Text = 'x5';
            app.x5EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.x5EditField_4.ValueChangedFcn = createCallbackFcn(app, @x5EditField_4ValueChanged, true);
            app.x1EditField_5Label = uilabel(app.UIFigure);
            app.x1EditField_5Label.Text = 'x1+';
            app.x1EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.x1EditField_5.ValueChangedFcn = createCallbackFcn(app, @x1EditField_5ValueChanged, true);
            app.x2EditField_5Label = uilabel(app.UIFigure);
            app.x2EditField_5Label.Text = 'x2+';
            app.x2EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.x2EditField_5.ValueChangedFcn = createCallbackFcn(app, @x2EditField_5ValueChanged, true);
            app.x3EditField_4Label = uilabel(app.UIFigure);
            app.x3EditField_4Label.Text = 'x3+';
            app.x3EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.x3EditField_4.ValueChangedFcn = createCallbackFcn(app, @x3EditField_4ValueChanged, true);
            app.x4EditField_6Label = uilabel(app.UIFigure);
            app.x4EditField_6Label.Text = 'x4+';
            app.x4EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.x4EditField_6.ValueChangedFcn = createCallbackFcn(app, @x4EditField_6ValueChanged, true);
            app.x5EditField_5Label = uilabel(app.UIFigure);
            app.x5EditField_5Label.Text = 'x5';
            app.x5EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.x5EditField_5.ValueChangedFcn = createCallbackFcn(app, @x5EditField_5ValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Кол-во переменных';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'1', '2', '3', '4', '5'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Tooltip = {'Необязательное поле'};
            app.DropDown.Value = '5';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'Кол-во уравнений';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'1', '2', '3', '4', '5'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Tooltip = {'Необязательное поле'};
            app.DropDown_2.Value = '5';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Решить';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.x1EditFieldLabel = uilabel(app.UIFigure);
            app.x1EditFieldLabel.Text = 'x1+';
            app.x1EditField = uieditfield(app.UIFigure, 'numeric');
            app.x1EditField.ValueChangedFcn = createCallbackFcn(app, @x1EditFieldValueChanged, true);
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Очистить';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = 'Ответ:';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = 'Заполнение с левого верхнего края';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end